classdef MicroLanD_nutrientpulsing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DisconnectButton         matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        AutomatedoperationPanel  matlab.ui.container.Panel
        Port4Button              matlab.ui.control.StateButton
        Port3Button              matlab.ui.control.StateButton
        Port2Button              matlab.ui.control.StateButton
        Port1Button              matlab.ui.control.StateButton
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        TimestepsecPanel         matlab.ui.container.Panel
        EditField                matlab.ui.control.NumericEditField
        ManualoperationPanel     matlab.ui.container.Panel
        StopButton_2             matlab.ui.control.Button
        LowButton                matlab.ui.control.Button
        HighButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        global t0 counter x y;               
        t0 = [];
        x = [];
        y = [];
        counter = 0;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global step_t
            value = app.EditField.Value;
            
            step_t = value;
        end

        % Button pushed function: HighButton
        function HighButtonPushed(app, event)
            global a loopFlag t0 counter x y;
            
            writeDigitalPin(a,'D2',1);
            writeDigitalPin(a,'D3',1);
            writeDigitalPin(a,'D4',1);
            writeDigitalPin(a,'D5',1);
            loopFlag = true;
            if isempty(t0) == 1
                t0 = tic;
            end
            while true
                pause(0.3);
                if (loopFlag == false)
                    break;
                end
                x = [x toc(t0)];
                y = [y 1];
                counter = counter + 1;
                if counter <= 30
                    plot(app.UIAxes,x,y,'-r','LineWidth',2)
                    axis(app.UIAxes,[0 9 -0.1 1.1]);
                    app.UIAxes.XTick = [0 3 6 9];
                    app.UIAxes.XTickLabel = ['0.0';'3.0';'6.0';'9.0'];
                else
                    plot(app.UIAxes,x(counter-30:counter),y(counter-30:counter),'-r','LineWidth',2);
                    axis(app.UIAxes,[x(counter-30) x(counter) -0.1 1.1]);
                    app.UIAxes.XTick = [x(counter-30) x(counter-20) x(counter-10) x(counter)];
                    app.UIAxes.XTickLabel = {num2str(round(x(counter-30),1));num2str(round(x(counter-20),1));num2str(round(x(counter-10),1));num2str(round(x(counter),1))};
                end
            end
        end

        % Button pushed function: LowButton
        function LowButtonPushed(app, event)
            global a loopFlag t0 counter x y;
            
            writeDigitalPin(a,'D2',0);
            writeDigitalPin(a,'D3',0);
            writeDigitalPin(a,'D4',0);
            writeDigitalPin(a,'D5',0);
            loopFlag = true;
            if isempty(t0) == 1
                t0 = tic;
            end
            while true
                pause(0.3);
                if (loopFlag == false)
                    break;
                end
                x = [x toc(t0)];
                y = [y 0];
                counter = counter + 1;
                if counter <= 30
                    plot(app.UIAxes,x,y,'-r','LineWidth',2)
                    axis(app.UIAxes,[0 9 -0.1 1.1]);
                    app.UIAxes.XTick = [0 3 6 9];
                    app.UIAxes.XTickLabel = ['0.0';'3.0';'6.0';'9.0'];
                else
                    plot(app.UIAxes,x(counter-30:counter),y(counter-30:counter),'-r','LineWidth',2);
                    axis(app.UIAxes,[x(counter-30) x(counter) -0.1 1.1]);
                    app.UIAxes.XTick = [x(counter-30) x(counter-20) x(counter-10) x(counter)];
                    app.UIAxes.XTickLabel = {num2str(round(x(counter-30),1));num2str(round(x(counter-20),1));num2str(round(x(counter-10),1));num2str(round(x(counter),1))};
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global a loopFlag step_t;
            
            loopFlag = true;
            %step_t = 1;                        % fluctuation time step (high)
            M = csvread('Sequence.csv',1,1);
            N = size(M,1);

            for i = 1:N
                if (loopFlag == false)
                    break;
                end
                writeDigitalPin(a,'D2',M(i,1));
                writeDigitalPin(a,'D3',M(i,2));
                writeDigitalPin(a,'D4',M(i,3));
                writeDigitalPin(a,'D5',M(i,4));
                
                if M(i,1) == 1
                    app.Port1Button.Text = 'High';
                    app.Port1Button.BackgroundColor = [0.635 0.078 0.184];
                else
                    app.Port1Button.Text = 'Low';
                    app.Port1Button.BackgroundColor = [0 0.447 0.741];
                end
                if M(i,2) == 1
                    app.Port2Button.Text = 'High';
                    app.Port2Button.BackgroundColor = [0.635 0.078 0.184];
                else
                    app.Port2Button.Text = 'Low';
                    app.Port2Button.BackgroundColor = [0 0.447 0.741];
                end
                if M(i,3) == 1
                    app.Port3Button.Text = 'High';
                    app.Port3Button.BackgroundColor = [0.635 0.078 0.184];
                else
                    app.Port3Button.Text = 'Low';
                    app.Port3Button.BackgroundColor = [0 0.447 0.741];
                end
                if M(i,4) == 1
                    app.Port4Button.Text = 'High';
                    app.Port4Button.BackgroundColor = [0.635 0.078 0.184];
                else
                    app.Port4Button.Text = 'Low';
                    app.Port4Button.BackgroundColor = [0 0.447 0.741];
                end
                
                pause(step_t)
            end
            app.Port1Button.Text = 'Port 1';
            app.Port1Button.BackgroundColor = [0.149 0.149 0.149];
            app.Port2Button.Text = 'Port 2';
            app.Port2Button.BackgroundColor = [0.149 0.149 0.149];
            app.Port3Button.Text = 'Port 3';
            app.Port3Button.BackgroundColor = [0.149 0.149 0.149];
            app.Port4Button.Text = 'Port 4';
            app.Port4Button.BackgroundColor = [0.149 0.149 0.149];            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global loopFlag;
            
            loopFlag = false;
            app.Port1Button.Text = 'Port 1';
            app.Port1Button.BackgroundColor = [0.149 0.149 0.149];
            app.Port2Button.Text = 'Port 2';
            app.Port2Button.BackgroundColor = [0.149 0.149 0.149];
            app.Port3Button.Text = 'Port 3';
            app.Port3Button.BackgroundColor = [0.149 0.149 0.149];
            app.Port4Button.Text = 'Port 4';
            app.Port4Button.BackgroundColor = [0.149 0.149 0.149];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global a;
            
            a = arduino('COM5','Uno');
            fopen(a)
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            clc
            clear all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-time monitor')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Signal')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.ManualoperationPanel = uipanel(app.UIFigure);
            app.ManualoperationPanel.Title = 'Manual operation';
            app.HighButton = uibutton(app.ManualoperationPanel, 'push');
            app.HighButton.ButtonPushedFcn = createCallbackFcn(app, @HighButtonPushed, true);
            app.HighButton.Text = 'High';
            app.LowButton = uibutton(app.ManualoperationPanel, 'push');
            app.LowButton.ButtonPushedFcn = createCallbackFcn(app, @LowButtonPushed, true);
            app.LowButton.Text = 'Low';
            app.StopButton_2 = uibutton(app.ManualoperationPanel, 'push');
            app.StopButton_2.Text = 'Stop';
            app.AutomatedoperationPanel = uipanel(app.UIFigure);
            app.AutomatedoperationPanel.Title = 'Automated operation';
            app.TimestepsecPanel = uipanel(app.AutomatedoperationPanel);
            app.TimestepsecPanel.Title = 'Time step (sec)';
            app.EditField = uieditfield(app.TimestepsecPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.StartButton = uibutton(app.AutomatedoperationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AutomatedoperationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Port1Button = uibutton(app.AutomatedoperationPanel, 'state');
            app.Port1Button.Text = 'Port 1';
            app.Port2Button = uibutton(app.AutomatedoperationPanel, 'state');
            app.Port2Button.Text = 'Port 2';
            app.Port3Button = uibutton(app.AutomatedoperationPanel, 'state');
            app.Port3Button.Text = 'Port 3';
            app.Port4Button = uibutton(app.AutomatedoperationPanel, 'state');
            app.Port4Button.Text = 'Port 4';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicroLanD_nutrientpulsing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end